classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MaxLengthofshortestsideEditFieldLabel  matlab.ui.control.Label
        MaxLengthofshortestsideEditField  matlab.ui.control.NumericEditField
        PrimitivePythagoreanTripleFinderLabel  matlab.ui.control.Label
        CalculatePythagoreanTriplesButton  matlab.ui.control.Button
        PythagoreanTriplesTextAreaLabel  matlab.ui.control.Label
        PythagoreanTriplesTextArea  matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Callback function: CalculatePythagoreanTriplesButton, 
        % PythagoreanTriplesTextArea, TextArea
        function CalculatePythagoreanTriplesButtonPushed(app, event)
            L=app.MaxLengthofshortestsideEditField.Value;
            % L is the limit for the shortest side: if you want all the pythagorean
            % triples with smallest side length < "n", change L to n. On my computer,
            % once L goes above about 5000, it really starts to slow down the program.
            
            Output=[];
            % Displays the output array of triples.
            
            for D=1:1:L/2
    % D is the distance between sides B and C.
            
    for A=D:2:L
        B=(A^2-D^2)/(2*D);
        C=B+D;
        % My method is to be given side length A, and difference, D, between
        % side lengths C and B. With these parameters given, there will always
        % be a right triangle with side length B=(A^2-D^2)/(2*D), and C=B+D.
            
            
        if (mod(B,1)==0 && B>1)
            % This just checks to make sure the side lengths are whole
            % numbers.
            
            for d=A:-1:1
                % d siplifies the triangle down as much as possible.
            
                if (mod(B/d,1)==0 && mod(C/d,1)==0)
                    % This sees if the simplification is a whole number: we
                    % want 6,8,10 to go to 3,4,5 but NOT 1.5,2,2.5.
            
                    Output=[Output;reshape(sortrows([A/d; B/d; C/d;]),[1,3])];
                    % This adds our new, simplified triangle to the list of
                    % other ones we found. This also puts the triangles in
                    % the correct order. For example, it converts a 3,5,4
                    % triangle to 3,4,5.
            
                    break
                end
            end
        end
    end
            end
            Output=unique(Output,'rows');
            % We don't want to count a triangle more than once because of the
            % simplification (for example: 6,8,10 to 3,4,5) and the original (3,4,5).
            
            % The rest of the rows just calculate the number of unique, fully
            % simplified pythagorean triples. It will then display all of them and tell
            % you how many were found.
            app.PythagoreanTriplesTextArea.Value = cellstr(num2str(Output));
        end

        % Value changed function: MaxLengthofshortestsideEditField
        function MaxLengthofshortestsideEditFieldValueChanged(app, event)
            value = app.MaxLengthofshortestsideEditField.Value;
        end

        % Callback function
        function PrimitivePythagoreanTriplesTextAreaValueChanged(app, event)
            output = app.PrimitivePythagoreanTriplesTextArea.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.MaxLengthofshortestsideEditFieldLabel = uilabel(app.UIFigure);
            app.MaxLengthofshortestsideEditFieldLabel.Text = 'Max Length of shortest side';
            app.MaxLengthofshortestsideEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxLengthofshortestsideEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLengthofshortestsideEditFieldValueChanged, true);
            app.PrimitivePythagoreanTripleFinderLabel = uilabel(app.UIFigure);
            app.PrimitivePythagoreanTripleFinderLabel.Text = 'Primitive Pythagorean Triple Finder';
            app.CalculatePythagoreanTriplesButton = uibutton(app.UIFigure, 'push');
            app.CalculatePythagoreanTriplesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePythagoreanTriplesButtonPushed, true);
            app.CalculatePythagoreanTriplesButton.Text = 'Calculate Pythagorean Triples';
            app.PythagoreanTriplesTextAreaLabel = uilabel(app.UIFigure);
            app.PythagoreanTriplesTextAreaLabel.Text = 'Pythagorean Triples:';
            app.PythagoreanTriplesTextArea = uitextarea(app.UIFigure);
            app.PythagoreanTriplesTextArea.ValueChangedFcn = createCallbackFcn(app, @CalculatePythagoreanTriplesButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end